classdef LoRa_Placement_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        SystemSettingsPanel           matlab.ui.container.Panel
        GridLayout14                  matlab.ui.container.GridLayout
        TabGroup3                     matlab.ui.container.TabGroup
        LoRaSettingsTab               matlab.ui.container.Tab
        GridLayout9                   matlab.ui.container.GridLayout
        RXSensitivitydBmSpinner       matlab.ui.control.Spinner
        RXSensitivitydBmSpinnerLabel  matlab.ui.control.Label
        TXPowerdBmSpinner             matlab.ui.control.Spinner
        TXPowerdBmSpinnerLabel        matlab.ui.control.Label
        FrequencyMHzEditField         matlab.ui.control.EditField
        FrequencyMHzEditFieldLabel    matlab.ui.control.Label
        AntennaSettingsTab            matlab.ui.container.Tab
        GridLayout10                  matlab.ui.container.GridLayout
        Node1AntennaHeightmSlider     matlab.ui.control.Slider
        Node1AntennaHeightLabel       matlab.ui.control.Label
        GatewayAntennaHeightmSlider   matlab.ui.control.Slider
        GatewayAntennaHeightLabel     matlab.ui.control.Label
        SETButton                     matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        GatewayTab                    matlab.ui.container.Tab
        GridLayout8_3                 matlab.ui.container.GridLayout
        SameasStartPiontCheckBox_5    matlab.ui.control.CheckBox
        LatitudeEditField_7           matlab.ui.control.EditField
        LatitudeEditField_7Label      matlab.ui.control.Label
        LongitudeEditField_7          matlab.ui.control.EditField
        LongitudeEditField_7Label     matlab.ui.control.Label
        NodeTab                       matlab.ui.container.Tab
        GridLayout8_2                 matlab.ui.container.GridLayout
        LatitudeEditField_6           matlab.ui.control.EditField
        LatitudeEditField_6Label      matlab.ui.control.Label
        LongitudeEditField_6          matlab.ui.control.EditField
        LongitudeEditField_6Label     matlab.ui.control.Label
        RouteSettingsPanel            matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        LoadRouteButton               matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        GPXFILETab                    matlab.ui.container.Tab
        GridLayout5                   matlab.ui.container.GridLayout
        RouteNameEditField            matlab.ui.control.EditField
        RouteNameEditFieldLabel       matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        SelectFileButton              matlab.ui.control.Button
        STARTTab                      matlab.ui.container.Tab
        GridLayout6                   matlab.ui.container.GridLayout
        LatitudeEditField             matlab.ui.control.EditField
        LatitudeEditFieldLabel        matlab.ui.control.Label
        LongitudeEditField            matlab.ui.control.EditField
        LongitudeEditFieldLabel       matlab.ui.control.Label
        ENDTab                        matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        LatitudeEditField_2           matlab.ui.control.EditField
        LatitudeEditField_2Label      matlab.ui.control.Label
        LongitudeEditField_2          matlab.ui.control.EditField
        LongitudeEditField_2Label     matlab.ui.control.Label
        LoRaTrakingLabel              matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        GridLayout13                  matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        NodeGatewayPacementCalculatorLabel  matlab.ui.control.Label
        TabGroup4                     matlab.ui.container.TabGroup
        RouteOverviewTab              matlab.ui.container.Tab
        ElevationTab                  matlab.ui.container.Tab
        DistanceTab                   matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        Gauge                         matlab.ui.control.Gauge
        GaugeLabel                    matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        GatewayNodeViewsButtonGroup   matlab.ui.container.ButtonGroup
        RayTraceButton                matlab.ui.control.ToggleButton
        CoverageButton                matlab.ui.control.ToggleButton
        LineofSightButton             matlab.ui.control.ToggleButton
        AllButton                     matlab.ui.control.ToggleButton
        NodeButton                    matlab.ui.control.ToggleButton
        GatewayButton                 matlab.ui.control.ToggleButton
        NoneButton_2                  matlab.ui.control.ToggleButton
        NodePatternButton             matlab.ui.control.ToggleButton
        GatewayPatternButton          matlab.ui.control.ToggleButton
        LinkSitesButton               matlab.ui.control.ToggleButton
        RouteViewsButtonGroup         matlab.ui.container.ButtonGroup
        ElevationButton               matlab.ui.control.ToggleButton
        RouteButton                   matlab.ui.control.ToggleButton
        NoneButton                    matlab.ui.control.ToggleButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        file_name           % Description
        Start_Point         % Description
        End_Point           % Description
        Center_pos          % Description
        route               % Description
        disp_1              % Description
        Layout_Pos          % Description
        antenna_script      % Description
        route_plot          % Description
        elevation_plot      % Description
        GateWay_lat         % Description
        GateWay_lon         % Description
        Node1_lon           % Description
        Node1_lat           % Description
      
        arrayObject % Description
        tx % Description
        rx % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SelectFileButton, TextArea, TextArea
        function SelectFileButtonPushed(app, event)
            [app.file_name,~] = uigetfile('*.gpx');  %open gpx file
            app.TextArea.Value = app.file_name;

            if app.file_name
                app.LoadRouteButton.Enable ="on";
            else
                app.LoadRouteButton.Enable ="off";
            end
        end

        % Button pushed function: LoadRouteButton
        function LoadRouteButtonPushed(app, event)
            % Load GPX file

            app.file_name = string(app.TextArea.Value);

            app.route = gpxread(app.file_name,'FeatureType','track');
            
            % App Sates Setup
            app.RouteViewsButtonGroup.Enable = "on";
            app.SelectFileButton.Enable = "off";
            app.SameasStartPiontCheckBox_5.Value = true;
            app.LatitudeEditField_7 = app.LatitudeEditField;
            app.LongitudeEditField_7 = app.LongitudeEditField;

            % Route Data Info Tabs
            app.route_plot = geoaxes(app.RouteOverviewTab);
            geoplot(app.route_plot,app.route.Latitude,app.route.Longitude);
            
            % Route Info
            timeStr = strrep(app.route.Time,'.000Z','');
            t = datetime(timeStr,'InputFormat','uuuu-MM-dd''T''HH:mm:ss.SSZ','TimeZone','UTC');
            
            % Elevation Info
            % app.elevation_plot = axes(app.ElevationTab);
            % app.ElevationTab
            % area(timeofday(t),app.route.Elevation)
            
            h = axes(app.ElevationTab);
            plot(h,timeofday(t),app.route.Elevation)

            % Distance Info

            e = wgs84Ellipsoid;
            lat = app.route.Latitude;
            lon = app.route.Longitude;
            d = distance(lat(1:end-1), lon(1:end-1), lat(2:end), lon(2:end), e);
            % d = d * unitsratio('sm', 'meter');
            d = sum(d)/1000;
            app.Gauge.Value = d;
            disp(d)
            app.Label.Text = string(d);


        end

        % Selection changed function: RouteViewsButtonGroup
        function RouteViewsButtonGroupSelectionChanged(app, event)
            selectedButton = app.RouteViewsButtonGroup.SelectedObject;
            
            % Figure Placement Math
            app.Layout_Pos = [app.UIFigure.Position(1)+app.UIFigure.Position(3),app.UIFigure.Position(2),app.UIFigure.Position(3)/2,app.UIFigure.Position(4)];
            
            
            if selectedButton == app.NoneButton

                delete(app.disp_1);
            end
            
            if selectedButton == app.RouteButton

                delete(app.disp_1);

                app.disp_1 = uifigure;
                app.disp_1.Position = app.Layout_Pos;

                Route_lat = app.route.Latitude;
                Route_log = app.route.Longitude; 

                app.route_plot = geoaxes(app.disp_1);
                % app.route_plot = geoaxes(app.RouteOverviewTab);
                geoplot(app.route_plot,Route_lat,Route_log);


            end

            if selectedButton == app.ElevationButton
                
                % Clean Figures
                delete(app.disp_1);
          
                app.disp_1 = uifigure;
                app.disp_1.Position = app.Layout_Pos;
        
                timeStr = strrep(app.route.Time,'.000Z','');
                t = datetime(timeStr,'InputFormat','uuuu-MM-dd''T''HH:mm:ss.SSZ','TimeZone','UTC');

                app.elevation_plot = axes(app.disp_1);
                plot(app.elevation_plot,timeofday(t),app.route.Elevation)
                   
            end
        end

        % Selection changed function: GatewayNodeViewsButtonGroup
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.GatewayNodeViewsButtonGroup.SelectedObject;

            app.Layout_Pos = [app.UIFigure.Position(1)+app.UIFigure.Position(3),app.UIFigure.Position(2),app.UIFigure.Position(3)/2,app.UIFigure.Position(4)];

            tx = app.tx;
            rx = app.rx;
            
            % rx = rxsite('Name', 'Gateway',"Latitude", app.GateWay_lon, "Longitude",app.GateWay_lat, "AntennaHeight", app.GatewayAntennaHeightmSlider.Value, ReceiverSensitivity=app.RXSensitivitydBmSpinner.Value, Antenna=app.arrayObject);
            % tx = txsite('Name','Node1',"Latitude",app.Node1_lon,"Longitude",app.Node1_lat,"AntennaHeight",app.Node1AntennaHeightmSlider.Value,"TransmitterPower",app.TXPowerdBmSpinner.Value,"TransmitterFrequency",str2double(string(app.FrequencyMHzEditField.Value))*1e6,"Antenna",app.arrayObject);

            switch selectedButton
                %----------------------------------------------------------------------------------------
                case app.NoneButton_2
                %----------------------------------------------------------------------------------------
                    wmclose('all')
                    
                %----------------------------------------------------------------------------------------
                case app.GatewayButton
                %----------------------------------------------------------------------------------------
                    wmclose('all')
                
                    wmmarker(app.GateWay_lon,app.GateWay_lat,'Description',sprintf('%s<br>%s</br><br>%s</br>',app.GateWay_lon, app.GateWay_lat),'OverlayName','Gateway');           
                %----------------------------------------------------------------------------------------
                case app.NodeButton
                %----------------------------------------------------------------------------------------
                    wmclose('all')

                    wmmarker(app.Node1_lon,app.Node1_lat,'Description',sprintf('%s<br>%s</br><br>%s</br>',app.Node1_lon, app.Node1_lat),'OverlayName','Gateway');
                %----------------------------------------------------------------------------------------
                case app.AllButton
                %----------------------------------------------------------------------------------------
            
                    wmclose('all')
    
                    wmmarker(app.GateWay_lon,app.GateWay_lat,'Description',sprintf('%s<br>%s</br><br>%s</br>',app.GateWay_lon, app.GateWay_lat),'OverlayName','Gateway');
                    wmmarker(app.Node1_lon,app.Node1_lat,'Description',sprintf('%s<br>%s</br><br>%s</br>',app.Node1_lon,app.Node1_lat),'OverlayName','Node1');

                %----------------------------------------------------------------------------------------
                case app.LinkSitesButton
                %----------------------------------------------------------------------------------------
                    viewer = siteviewer('Terrain','gmted2010','Basemap','topographic');
                    viewer.Position = app.Layout_Pos;
                
                    link(rx,tx,"PropagationModel","longley-rice")
                %----------------------------------------------------------------------------------------
                case app.GatewayPatternButton
                %----------------------------------------------------------------------------------------
                    pattern(rx,str2double(string(app.FrequencyMHzEditField.Value))*1e6)

                %----------------------------------------------------------------------------------------
                case app.NodePatternButton
                %----------------------------------------------------------------------------------------
                    pattern(tx,str2double(string(app.FrequencyMHzEditField.Value))*1e6)

                %----------------------------------------------------------------------------------------
                case app.LineofSightButton
                %----------------------------------------------------------------------------------------
                    los(rx,tx)
                
                
                %----------------------------------------------------------------------------------------
                case app.CoverageButton
                %----------------------------------------------------------------------------------------
                    pm = propagationModel("longley-rice","AntennaPolarization","vertical")
                    coverage(tx,rx,pm,"SignalStrengths",app.RXSensitivitydBmSpinner.Value:-5,"MaxRange",20000) 


                %----------------------------------------------------------------------------------------
                case app.RayTraceButton
                %----------------------------------------------------------------------------------------
                    pm = propagationModel("longley-rice","AntennaPolarization","vertical")    
                    raytrace(app.tx,app.rx,"Type","power")

            end
        end

        % Button pushed function: SETButton
        function SETButtonPushed(app, event)


            %----------------------------------------------------------------------------------------
            % Setup Antenna
            %----------------------------------------------------------------------------------------
            % SubObject = design(dipole, 868*1e6);
            % app.arrayObject = design(linearArray('Element', SubObject), 868*1e6, SubObject);
            % app.arrayObject.Element.Conductor.Name = 'Copper';
            % app.arrayObject.Element.Conductor.Conductivity = 5.96*1e7;
            % app.arrayObject.Element.Conductor.Thickness = 3.556e-05;
            % app.arrayObject.Element.Tilt = 90;
            % app.arrayObject.Element.TiltAxis = [0, -1, 0];
            % app.arrayObject.Element.Load.Impedance = '';
            % app.arrayObject.NumElements = 6;
            % app.arrayObject.Tilt = 90;
            % app.arrayObject.TiltAxis = [0, -1, 0];
            %----------------------------------------------------------------------------------------
            
 
            %----------------------------------------------------------------------------------------
            % Get Coordinates
            %----------------------------------------------------------------------------------------
            if app.SameasStartPiontCheckBox_5.Value

                app.GateWay_lon = str2double(string(app.LongitudeEditField.Value));
                app.GateWay_lat = str2double(string(app.LatitudeEditField.Value));
            
            else
                app.GateWay_lon = str2double(string(app.LongitudeEditField_7.Value));
                app.GateWay_lat = str2double(string(app.LatitudeEditField_7.Value));

            end

            app.Node1_lon = str2double(string(app.LongitudeEditField_6.Value));
            app.Node1_lat = str2double(string(app.LatitudeEditField_6.Value));


            %----------------------------------------------------------------------------------------
            % Site Settings
            %----------------------------------------------------------------------------------------

            app.rx = rxsite('Name', 'Gateway',"Latitude", app.GateWay_lon, "Longitude",app.GateWay_lat, "AntennaHeight", app.GatewayAntennaHeightmSlider.Value, ReceiverSensitivity=app.RXSensitivitydBmSpinner.Value);
            app.tx = txsite('Name','Node1',"Latitude",app.Node1_lon,"Longitude",app.Node1_lat,"AntennaHeight",app.Node1AntennaHeightmSlider.Value,"TransmitterPower",app.TXPowerdBmSpinner.Value,"TransmitterFrequency",str2double(string(app.FrequencyMHzEditField.Value))*1e6);
            tx = app.tx;
            rx = app.rx;
            
            %----------------------------------------------------------------------------------------
            % Tab Info
            %----------------------------------------------------------------------------------------

            [az,el] = angle(tx,rx);
            [az1,el1] = angle(rx,tx);
            app.UITable.Data =[["Distance between Sites", (distance(tx,rx)/1000), 'km']; ["Received Signal Strength", sigstrength(rx,tx), 'dBm']; ["Receiver Margin", abs(rx.ReceiverSensitivity - sigstrength(rx,tx)), 'dBm']; ["Azimuth (tx->rx)", az, 'degrees']; ["Elevation (tx->rx)", rad2deg(el), 'degrees'];["Azimuth (rx->tx)", (az1), 'degrees']; ["Elevation (rx->tx)", rad2deg(el1), 'degrees']];
            % [az,el] = angle(tx,rx);
            % app.UITable.Data(2) = ["Azimuth (tx->rx)", az, 'degrees'];
            % app.UITable.Data(3) = ["Elevation (tx->rx)", el, 'degrees'];
            % 
            % app.UITable.Data(4) = ;
            % app.UITable.Data(5) = ["Elevation (rx->tx)", el1, 'degrees'];
            
            %----------------------------------------------------------------------------------------
            % GUI Settings
            %----------------------------------------------------------------------------------------
            % app.AntennaViewsButtonGroup.Enable = "on";
            app.GatewayNodeViewsButtonGroup.Enable = "on";


        end

        % Callback function: not associated with a component
        function AntennaViewsButtonGroupSelectionChanged(app, event)
            selectedButton = app.AntennaViewsButtonGroup.SelectedObject;
            
            app.Layout_Pos = [app.UIFigure.Position(1)+app.UIFigure.Position(3),app.UIFigure.Position(2),app.UIFigure.Position(3)/2,app.UIFigure.Position(4)];

            switch selectedButton
                %----------------------------------------------------------------------------------------
                case app.NoneButton_3
                %----------------------------------------------------------------------------------------
                    close all
                %----------------------------------------------------------------------------------------
                case app.LayoutButton
                %----------------------------------------------------------------------------------------
                    % figure;
                    close all
                    show(app.arrayObject)    
                %----------------------------------------------------------------------------------------
                case app.PatternButton
                %----------------------------------------------------------------------------------------
                    close all
                    pattern(app.arrayObject, str2double(string(app.FrequencyMHzEditField.Value))*1e6, 'Termination', 50);
                %----------------------------------------------------------------------------------------
                case app.AzimuthButton
                %----------------------------------------------------------------------------------------
                    close all
                    pattern(app.arrayObject, str2double(string(app.FrequencyMHzEditField.Value))*1e6,  0:5:360, 0, 'Termination', 50);
                %----------------------------------------------------------------------------------------
                case app.ElevationButton_2
                %----------------------------------------------------------------------------------------
                    close all
                    pattern(app.arrayObject, str2double(string(app.FrequencyMHzEditField.Value))*1e6, 0, 0:5:360, 'Termination', 50);
                %----------------------------------------------------------------------------------------
            end
            

        end

        % Value changed function: SameasStartPiontCheckBox_5
        function SameasStartPiontCheckBox_5ValueChanged(app, event)
            value = app.SameasStartPiontCheckBox_5.Value;
            
            if value

                app.LongitudeEditField_7.Enable = "off";
                app.LatitudeEditField_7.Enable = "off";
                
            else

                app.LongitudeEditField_7.Enable = "on";
                app.LatitudeEditField_7.Enable = "on";

            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {707, 707, 707};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {707, 707};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {411, '1x', 202};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoRaTrakingLabel = uilabel(app.GridLayout2);
            app.LoRaTrakingLabel.Text = 'LoRa Traking';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.RouteSettingsPanel = uipanel(app.GridLayout3);
            app.RouteSettingsPanel.Title = 'Route Settings';
            app.GridLayout4 = uigridlayout(app.RouteSettingsPanel);
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.GPXFILETab = uitab(app.TabGroup);
            app.GPXFILETab.Title = 'GPX FILE';
            app.GridLayout5 = uigridlayout(app.GPXFILETab);
            app.SelectFileButton = uibutton(app.GridLayout5, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'2023-07-20_1221277482_TTR 30k edited (29.4).gpx'};
            app.RouteNameEditFieldLabel = uilabel(app.GridLayout5);
            app.RouteNameEditFieldLabel.Text = 'Route Name';
            app.RouteNameEditField = uieditfield(app.GridLayout5, 'text');
            app.RouteNameEditField.Value = 'TTR 30k';
            app.STARTTab = uitab(app.TabGroup);
            app.STARTTab.Title = 'START';
            app.GridLayout6 = uigridlayout(app.STARTTab);
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout6);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.GridLayout6, 'text');
            app.LongitudeEditField.Value = '-33.633440';
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout6);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.GridLayout6, 'text');
            app.LatitudeEditField.Value = '19.008281';
            app.ENDTab = uitab(app.TabGroup);
            app.ENDTab.Title = 'END';
            app.GridLayout7 = uigridlayout(app.ENDTab);
            app.LongitudeEditField_2Label = uilabel(app.GridLayout7);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.LongitudeEditField_2 = uieditfield(app.GridLayout7, 'text');
            app.LongitudeEditField_2.Value = '-32.33776';
            app.LatitudeEditField_2Label = uilabel(app.GridLayout7);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.LatitudeEditField_2 = uieditfield(app.GridLayout7, 'text');
            app.LatitudeEditField_2.Value = '19.71713';
            app.LoadRouteButton = uibutton(app.GridLayout4, 'push');
            app.LoadRouteButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRouteButtonPushed, true);
            app.LoadRouteButton.Text = 'Load Route';
            app.SystemSettingsPanel = uipanel(app.GridLayout3);
            app.SystemSettingsPanel.Title = 'System Settings';
            app.GridLayout14 = uigridlayout(app.SystemSettingsPanel);
            app.TabGroup2 = uitabgroup(app.GridLayout14);
            app.GatewayTab = uitab(app.TabGroup2);
            app.GatewayTab.Title = 'Gateway';
            app.GridLayout8_3 = uigridlayout(app.GatewayTab);
            app.LongitudeEditField_7Label = uilabel(app.GridLayout8_3);
            app.LongitudeEditField_7Label.Text = 'Longitude';
            app.LongitudeEditField_7 = uieditfield(app.GridLayout8_3, 'text');
            app.LongitudeEditField_7.Enable = 'off';
            app.LongitudeEditField_7.Value = '-33.67709';
            app.LatitudeEditField_7Label = uilabel(app.GridLayout8_3);
            app.LatitudeEditField_7Label.Text = 'Latitude';
            app.LatitudeEditField_7 = uieditfield(app.GridLayout8_3, 'text');
            app.LatitudeEditField_7.Enable = 'off';
            app.LatitudeEditField_7.Value = '18.90382';
            app.SameasStartPiontCheckBox_5 = uicheckbox(app.GridLayout8_3);
            app.SameasStartPiontCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @SameasStartPiontCheckBox_5ValueChanged, true);
            app.SameasStartPiontCheckBox_5.Text = 'Same as Start Piont';
            app.SameasStartPiontCheckBox_5.Value = true;
            app.NodeTab = uitab(app.TabGroup2);
            app.NodeTab.Title = 'Node';
            app.GridLayout8_2 = uigridlayout(app.NodeTab);
            app.LongitudeEditField_6Label = uilabel(app.GridLayout8_2);
            app.LongitudeEditField_6Label.Text = 'Longitude';
            app.LongitudeEditField_6 = uieditfield(app.GridLayout8_2, 'text');
            app.LongitudeEditField_6.Value = '-33.66131';
            app.LatitudeEditField_6Label = uilabel(app.GridLayout8_2);
            app.LatitudeEditField_6Label.Text = 'Latitude';
            app.LatitudeEditField_6 = uieditfield(app.GridLayout8_2, 'text');
            app.LatitudeEditField_6.Value = '18.84186';
            app.SETButton = uibutton(app.GridLayout14, 'push');
            app.SETButton.ButtonPushedFcn = createCallbackFcn(app, @SETButtonPushed, true);
            app.SETButton.Text = 'SET';
            app.TabGroup3 = uitabgroup(app.GridLayout14);
            app.LoRaSettingsTab = uitab(app.TabGroup3);
            app.LoRaSettingsTab.Title = 'LoRa Settings';
            app.GridLayout9 = uigridlayout(app.LoRaSettingsTab);
            app.FrequencyMHzEditFieldLabel = uilabel(app.GridLayout9);
            app.FrequencyMHzEditFieldLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzEditField = uieditfield(app.GridLayout9, 'text');
            app.FrequencyMHzEditField.InputType = 'digits';
            app.FrequencyMHzEditField.Value = '868';
            app.TXPowerdBmSpinnerLabel = uilabel(app.GridLayout9);
            app.TXPowerdBmSpinnerLabel.Text = 'TX Power (dBm)';
            app.TXPowerdBmSpinner = uispinner(app.GridLayout9);
            app.TXPowerdBmSpinner.Limits = [0 22];
            app.TXPowerdBmSpinner.Value = 15;
            app.RXSensitivitydBmSpinnerLabel = uilabel(app.GridLayout9);
            app.RXSensitivitydBmSpinnerLabel.Text = 'RX Sensitivity (dBm)';
            app.RXSensitivitydBmSpinner = uispinner(app.GridLayout9);
            app.RXSensitivitydBmSpinner.Limits = [-150 -100];
            app.RXSensitivitydBmSpinner.Value = -121;
            app.AntennaSettingsTab = uitab(app.TabGroup3);
            app.AntennaSettingsTab.Title = 'Antenna Settings';
            app.GridLayout10 = uigridlayout(app.AntennaSettingsTab);
            app.GatewayAntennaHeightLabel = uilabel(app.GridLayout10);
            app.GatewayAntennaHeightLabel.Text = {'Gateway'; 'Antenna Height'; '(m)'};
            app.GatewayAntennaHeightmSlider = uislider(app.GridLayout10);
            app.GatewayAntennaHeightmSlider.Limits = [1 6];
            app.GatewayAntennaHeightmSlider.MajorTicks = [1 2 3 4 5 6];
            app.GatewayAntennaHeightmSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6'};
            app.GatewayAntennaHeightmSlider.MinorTicks = [1.5 2 3.5 4.5 5 5.5];
            app.GatewayAntennaHeightmSlider.Value = 1;
            app.Node1AntennaHeightLabel = uilabel(app.GridLayout10);
            app.Node1AntennaHeightLabel.Text = {'Node 1'; 'Antenna Height'; '(m)'};
            app.Node1AntennaHeightmSlider = uislider(app.GridLayout10);
            app.Node1AntennaHeightmSlider.Limits = [1 6];
            app.Node1AntennaHeightmSlider.MajorTicks = [1 2 3 4 5 6];
            app.Node1AntennaHeightmSlider.MinorTicks = [1.5 2 3.5 4.5 5 5.5];
            app.Node1AntennaHeightmSlider.Value = 1;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout13 = uigridlayout(app.CenterPanel);
            app.TabGroup4 = uitabgroup(app.GridLayout13);
            app.RouteOverviewTab = uitab(app.TabGroup4);
            app.RouteOverviewTab.Title = 'Route Overview';
            app.ElevationTab = uitab(app.TabGroup4);
            app.ElevationTab.Title = 'Elevation';
            app.DistanceTab = uitab(app.TabGroup4);
            app.DistanceTab.Title = 'Distance';
            app.GaugeLabel = uilabel(app.DistanceTab);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.DistanceTab, 'circular');
            app.Label = uilabel(app.DistanceTab);
            app.NodeGatewayPacementCalculatorLabel = uilabel(app.GridLayout13);
            app.NodeGatewayPacementCalculatorLabel.Text = 'Node/ Gateway Pacement Calculator';
            app.UITable = uitable(app.GridLayout13);
            app.UITable.ColumnName = {'Property'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.RightPanel);
            app.RouteViewsButtonGroup = uibuttongroup(app.GridLayout12);
            app.RouteViewsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RouteViewsButtonGroupSelectionChanged, true);
            app.RouteViewsButtonGroup.Enable = 'off';
            app.RouteViewsButtonGroup.Title = 'Route Views';
            app.NoneButton = uitogglebutton(app.RouteViewsButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.RouteButton = uitogglebutton(app.RouteViewsButtonGroup);
            app.RouteButton.Text = 'Route';
            app.ElevationButton = uitogglebutton(app.RouteViewsButtonGroup);
            app.ElevationButton.Text = 'Elevation';
            app.GatewayNodeViewsButtonGroup = uibuttongroup(app.GridLayout12);
            app.GatewayNodeViewsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.GatewayNodeViewsButtonGroup.Enable = 'off';
            app.GatewayNodeViewsButtonGroup.Title = 'Gateway\ Node Views';
            app.LinkSitesButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.LinkSitesButton.Text = 'Link Sites';
            app.GatewayPatternButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.GatewayPatternButton.Text = 'Gateway Pattern';
            app.NodePatternButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.NodePatternButton.Text = 'Node Pattern';
            app.NoneButton_2 = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.GatewayButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.GatewayButton.Text = 'Gateway';
            app.NodeButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.NodeButton.Text = 'Node';
            app.AllButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.AllButton.Text = 'All';
            app.LineofSightButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.LineofSightButton.Text = 'Line of Sight';
            app.CoverageButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.CoverageButton.Text = 'Coverage';
            app.RayTraceButton = uitogglebutton(app.GatewayNodeViewsButtonGroup);
            app.RayTraceButton.Text = 'Ray Trace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoRa_Placement_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end